classdef FFCProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PipelineDevelopmentPanel       matlab.ui.container.Panel
        InputDirectoryLabel            matlab.ui.control.Label
        OutputDirectoryLabel           matlab.ui.control.Label
        InputDirectoryTextLabel        matlab.ui.control.Label
        OutputDirectoryTextLabel       matlab.ui.control.Label
        BrowseOutputDirectorButton     matlab.ui.control.Button
        RunPipelineButton              matlab.ui.control.Button
        SavePipelineButton             matlab.ui.control.Button
        LoadPipelineButton             matlab.ui.control.Button
        BrowseInputDirectorButton      matlab.ui.control.Button
        R1FittingDropDown              matlab.ui.control.DropDown
        R1FittingDropDownLabel         matlab.ui.control.Label
        DeghostingLabel                matlab.ui.control.Label
        AutomaticDespikingCheckBox     matlab.ui.control.CheckBox
        DenoisingDropDown              matlab.ui.control.DropDown
        DenoisingDropDownLabel         matlab.ui.control.Label
        ExportOptionsPanel             matlab.ui.container.Panel
        CreateFigurePanel              matlab.ui.container.Panel
        ImageButton                    matlab.ui.control.Button
        kspaceButton                   matlab.ui.control.Button
        ExportscanparameterstoworkspaceButton  matlab.ui.control.Button
        ExportstackedimagesButton      matlab.ui.control.Button
        ExportallimagesButton          matlab.ui.control.Button
        ExportscantoworkspaceButton    matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        BackgroundselectionButton      matlab.ui.control.StateButton
        ReprocessingButton             matlab.ui.control.StateButton
        LoadDataSetButton              matlab.ui.control.Button
        RelaxometryPanel               matlab.ui.container.Panel
        ClusterAnalysisButton          matlab.ui.control.Button
        ColourAxisLowerLimitEditField  matlab.ui.control.NumericEditField
        ColourAxisLowerLimitEditFieldLabel  matlab.ui.control.Label
        ColourAxisUpperLimitEditField  matlab.ui.control.NumericEditField
        ColourAxisUpperLimitEditFieldLabel  matlab.ui.control.Label
        DisplayingasT1Button           matlab.ui.control.StateButton
        LogarithmicMapsButton          matlab.ui.control.StateButton
        CheckfitsButton                matlab.ui.control.StateButton
        GenerateROIDispersionCurveButton  matlab.ui.control.Button
        GenerateRelaxationMapsButton   matlab.ui.control.Button
        ScanListPanel                  matlab.ui.container.Panel
        ScanList                       matlab.ui.control.ListBox
        DisplayOptionsPanel            matlab.ui.container.Panel
        IndividualNormalisationButton  matlab.ui.control.StateButton
        FlipVerticallyButton           matlab.ui.control.Button
        FlipHorizontallyButton         matlab.ui.control.Button
        RotateImageButton              matlab.ui.control.Button
        ContrastSlider                 matlab.ui.control.Slider
        ContrastSliderLabel            matlab.ui.control.Label
        GammaSlider                    matlab.ui.control.Slider
        GammaSliderLabel               matlab.ui.control.Label
        BrightnessSlider               matlab.ui.control.Slider
        BrightnessSliderLabel          matlab.ui.control.Label
        CurrentImagePanel              matlab.ui.container.Panel
        MeasureSNRButton               matlab.ui.control.Button
        RegisterImagesButton           matlab.ui.control.Button
        ImageTypeDropDown              matlab.ui.control.DropDown
        ImageTypeDropDownLabel         matlab.ui.control.Label
        SliceDropDown                  matlab.ui.control.DropDown
        SliceDropDownLabel             matlab.ui.control.Label
        EvolutionTimeDropDown          matlab.ui.control.DropDown
        EvolutionTimeDropDownLabel     matlab.ui.control.Label
        EvolutionFieldDropDown         matlab.ui.control.DropDown
        EvolutionFieldDropDownLabel    matlab.ui.control.Label
        panel_recon                    matlab.ui.container.Panel
        RebuildImagesButton            matlab.ui.control.Button
        MultichannelDropDown           matlab.ui.control.DropDown
        MultichannelRecon              matlab.ui.control.Label
        FilterControlEditField         matlab.ui.control.NumericEditField
        FilterControlEditFieldLabel    matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        FilterDropDownLabel            matlab.ui.control.Label
        WindowSizeEditField            matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        WindowDropDownLabel            matlab.ui.control.Label
        FFTDropDown                    matlab.ui.control.DropDown
        SizeofFFTDropDownLabel         matlab.ui.control.Label
        axis_coordinates               matlab.ui.control.UIAxes
        axis_image                     matlab.ui.control.UIAxes
        axis_kspace                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentscan % Currently selected scan
        scan_data %actual processed scan data
        currentmap
        mask
        editableTags % Description
        minimumSize % Minimum Size of the GUI
    end
    
    methods (Access = private)
        
        function   activeimage(app)
            app.currentscan = app.ScanList.Value;
            if isempty(app.scan_data{app.currentscan}.rot_theta)
                app.scan_data{app.currentscan}.rot_theta = 0;
            end
            
            %generate string lists with appropriate evolution fields, times etc
            fft_list = cellfun(@num2str,{app.scan_data{app.currentscan}.samples,app.scan_data{app.currentscan}.samples*2,app.scan_data{app.currentscan}.samples*4},'UniformOutput',false);
            bevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.fieldpoints,2));
            tevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.timepoints(1,:),2));
            
            
            %populate the GUI with the lists
            app.FFTDropDown.Items = fft_list;
            app.EvolutionFieldDropDown.Items = bevol_list;
            app.EvolutionTimeDropDown.Items = tevol_list; 
            app.SliceDropDown.Items = sprintfc('%i',1:app.scan_data{app.currentscan}.slices);
            
            %allows use of indexing
            app.EvolutionFieldDropDown.ItemsData = 1:numel(app.EvolutionFieldDropDown.Items); %allows us to get indexes
            app.EvolutionTimeDropDown.ItemsData = 1:numel(app.EvolutionTimeDropDown.Items);
            app.SliceDropDown.ItemsData = 1:app.scan_data{app.currentscan}.slices;
            app.MultichannelDropDown.Items = ["SoS" "Average" 1:app.scan_data{app.currentscan}.n_receivers];
            app.MultichannelDropDown.ItemsData = 1:numel(app.MultichannelDropDown.Items);
            
            if length(app.MultichannelDropDown.ItemsData) <3
                app.SliceDropDown.Enable = 0;
            else
                app.SliceDropDown.Enable = 1;
            end 
            
            
            if length(app.SliceDropDown.ItemsData) ==1
                app.SliceDropDown.Enable = 0;
            else
                app.SliceDropDown.Enable = 1;
            end
            
            if length(app.EvolutionTimeDropDown.ItemsData) ==1
                app.EvolutionTimeDropDown.Enable = 0;
                app.GenerateROIDispersionCurveButton.Enable=0;
                app.GenerateRelaxationMapsButton.Enable =0;
                app.ClusterAnalysisButton.Enable =0;
                
            else
                app.EvolutionTimeDropDown.Enable = 1;
                app.GenerateROIDispersionCurveButton.Enable=1;
                app.GenerateRelaxationMapsButton.Enable =1;
                app.ClusterAnalysisButton.Enable =1;
            end
            
            if length(app.EvolutionFieldDropDown.ItemsData) ==1
                app.EvolutionFieldDropDown.Enable = 0;
            else
                app.EvolutionFieldDropDown.Enable = 1;
            end
            
            app.FFTDropDown.Value =  num2str(app.scan_data{app.currentscan}.fft_size);
            app.WindowSizeEditField.Value = app.scan_data{app.currentscan}.window_size;
            
            if strcmp(app.scan_data{app.currentscan}.window_function, 'none')
                app.scan_data{app.currentscan}.window_function = 'None';
            end
            
            app.WindowDropDown.Value = app.scan_data{app.currentscan}.window_function;
            
            if strcmp(app.scan_data{app.currentscan}.denoise_filter, 'none')
                app.scan_data{app.currentscan}.denoise_filter = 'None';
            end
            if isempty(app.scan_data{app.currentscan}.denoise_params)
                app.scan_data{app.currentscan}.denoise_params = 1;
            end
            
            if isempty(app.scan_data{app.currentscan}.multichannel_recon)
                app.scan_data{app.currentscan}.multichannel_recon = 1;
            end
            app.FilterDropDown.Value = app.scan_data{app.currentscan}.denoise_filter;
            app.FilterControlEditField.Value = app.scan_data{app.currentscan}.denoise_params;
            
            
            %kspace to image space with selected recon parameters
            app.scan_data{app.currentscan} = buildimages(app.scan_data{app.currentscan});
            
            app.SliceDropDown.Value = 1;
            app.EvolutionFieldDropDown.Value =1;
            app.EvolutionTimeDropDown.Value =1;
            app.MultichannelDropDown.Value =1;
            
            drawimages(app)
        end


        function drawimages(app)
            axis(app.axis_image,'image'); %forces image axis to rescale to the image matrix size
            axis(app.axis_kspace,'image');
            caxis(app.axis_image,'auto'); %initially use automatic colourscale, we can change to defined limits later if desired
            
            %get display params from the sliders
            brightness = app.BrightnessSlider.Value;
            gamma = app.GammaSlider.Value;
            contrast = app.ContrastSlider.Value;
            
            %get the currently selected field/time/slice
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;
            
            absolute_scale = app.IndividualNormalisationButton.Value; %how will the image intensity be scaled?
            
            ImageType = app.ImageTypeDropDown.Value; %magnitude, phase or map
            
%             a = app.scan_data{app.currentscan}.magkspace;
%             aSize = size(a);
%             disp(numel(app.EvolutionTimeDropDown.Items));
%             X = zeros(aSize(1),aSize(2), numel(app.SliceDropDown.Items),numel(app.EvolutionTimeDropDown.Items),numel(app.EvolutionFieldDropDown.Items));
%             
%             a = a(end, end,numel(app.SliceDropDown.Items),numel(app.EvolutionTimeDropDown.Items),numel(app.EvolutionFieldDropDown.Items)) = 0;
%             disp(ndims(a));
%             xSize = size(a);
%             X = X + app.scan_data{app.currentscan}.magkspace;
            displayedkspace = log(1+(app.scan_data{app.currentscan}.magkspace(:,:,disp_slice,disp_time,disp_field))); %kspace is easier to visualise on a log scale
            
            switch ImageType
                case "Magnitude"
                    displayedimage = app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field);
                    cmap = brighten(imadjust(colormap(app.axis_kspace,'gray'),[0 contrast],[],gamma),brightness); %adjust the default graylevel colour scale using the display parameters from the brightness/contrast/gamma sliders. A target is needed for the colormap to avoid MATLAB creating a new figure
                    %assign each axis a colourmap
                    colormap(app.axis_kspace.Parent,cmap);
                    colormap(app.axis_image.Parent,cmap);
                    
                case "Phase"
                    displayedimage = app.scan_data{app.currentscan}.phaseimage(:,:,disp_slice,disp_time,disp_field);
                    cmap = brighten(imadjust(colormap(app.axis_kspace,'gray'),[0 contrast],[],gamma),brightness);
                    colormap(app.axis_kspace.Parent,cmap);
                    colormap(app.axis_image.Parent,cmap);
                    
                case "Relaxometry Map"
                    app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value; %T1 or R1
                    caxis(app.axis_image,[app.ColourAxisLowerLimitEditField.Value,app.ColourAxisUpperLimitEditField.Value]); %get the limits from the GUI
                    colormap(app.axis_kspace,'gray'); %keep kspace grayscale
                    colormap(app.axis_image,'jet');
                    if app.scan_data{app.currentscan}.R1T1 == 0
                        if app.LogarithmicMapsButton.Value ==0
                            displayedimage = app.scan_data{app.currentscan}.T1Maps(:,:,1,disp_field);
                        else
                            displayedimage = log(app.scan_data{app.currentscan}.T1Maps(:,:,1,disp_field));
                        end
                    else
                        if app.LogarithmicMapsButton.Value ==0
                            displayedimage = app.scan_data{app.currentscan}.R1Maps(:,:,1,disp_field);
                        else
                            displayedimage = log(app.scan_data{app.currentscan}.R1Maps(:,:,1,disp_field));
                        end
                    end
            end
            
            if absolute_scale  && ImageType == "Magnitude" %only do imshow scaling on mag images - doesn't make sense for phase and relaxation maps have their own scaling
                max_level = max(app.scan_data{app.currentscan}.magimage(:));
                imshow(displayedimage,[0 255],'Parent',app.axis_image); %0.2 * max is a reasonable guess but should revisit this
                imagesc(app.axis_image,displayedimage);
            else
                
                imagesc(app.axis_kspace, displayedkspace);
                imagesc(app.axis_image,displayedimage);
            end
            
            
p=plot3(app.axis_coordinates,[0  0  0; 0  0  -1; 0  0  1], [0  -1  0; 0  1  0; 0  0  0], [1  0  0; 0  0  0; -1  0  0]);
grid off; 
axis(app.axis_coordinates,'off')

axis(app.axis_coordinates,[-1 1    -1 1    -1 1]);
p(1).LineWidth =3;
p(2).LineWidth =3;
p(3).LineWidth =3;

text(app.axis_coordinates,-1.2,0,0,'L','FontWeight','Bold','FontSize',14);
text(app.axis_coordinates,1.2,0,0,'R','FontWeight','Bold','FontSize',14);

text(app.axis_coordinates,0,-1.2,0,'I','FontWeight','Bold','FontSize',14)
text(app.axis_coordinates,0,1.2,0,'S','FontWeight','Bold','FontSize',14)

text(app.axis_coordinates,0,0,-1.2,'P','FontWeight','Bold','FontSize',14)
text(app.axis_coordinates,0,0,1.2,'A','FontWeight','Bold','FontSize',14)

ax = app.scan_data{app.currentscan}.orientation(1);
ay = app.scan_data{app.currentscan}.orientation(2);
az = app.scan_data{app.currentscan}.orientation(3);


% theta = app.scan_data{app.currentscan}.rot_theta;
% tform_rotation = ([...
%                cosd(theta)        0        sind(theta) ;...
%                0         1      0; ...
%                 -sind(theta)          0       cosd(theta)  ... 
%                 ]);
% 
% Rx = [1 0 0 ; 0 cosd(theta) -sind(theta);0 sind(theta) cosd(theta)];
% Ry = tform_rotation;
% Rz = [cosd(theta) -sind(theta) 0;sind(theta) cosd(theta) 0; 0 0 1];

 view(app.axis_coordinates,[0,1,0]);


camorbit(app.axis_coordinates,ax,0,'data',[1 0 0]); 
camorbit(app.axis_coordinates,ay,0,'data',[0 1 0]);
camorbit(app.axis_coordinates,az,0,'data',[0 0 1]);

% camorbit(app.axis_coordinates,theta,'camera',[0 1 1])
% rotate(app.axis_coordinates,[1 1 1],app.scan_data{app.currentscan}.rot_theta);


drawnow


%  campos(app.axis_coordinates,[0,0,0]*inv(Rz)*inv(Ry)*inv(Rx))


        end
        
        function updateimages(app)  %this method reconstructs the images in case of a change of filter or fft size - more time consuming than a simple redraw
            app.scan_data{app.currentscan}.fft_size = str2double(app.FFTDropDown.Value);
            app.scan_data{app.currentscan}.window_size = app.WindowSizeEditField.Value;
            app.scan_data{app.currentscan}.window_function = app.WindowDropDown.Value;
            app.scan_data{app.currentscan}.denoise_filter = app.FilterDropDown.Value;
            app.scan_data{app.currentscan}.denoise_params = app.FilterControlEditField.Value;
            app.scan_data{app.currentscan}.multichannel_recon = app.MultichannelDropDown.Value;
            app.scan_data{app.currentscan} = buildimages(app.scan_data{app.currentscan});
            drawimages(app)
            
        end
        
        %Any Functions for various different components that need to be set
        %up should happen here.
        function InitialSetup(app)
            app.FilterDropDown.Items = retrieveProcessNames('Noise');
            app.DenoisingDropDown.Items = retrieveProcessNames('Noise');
            app.R1FittingDropDown.Items = retrieveProcessNames('R1');
            app.editableTags = {'ScrollSlice','EvoFieldSlice','EvoTimeSlice'};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Give resolution for your previous screen:
            pause(2)
%             app.minimumSize = [app.UIFigure.Position(3),app.UIFigure.Position(4)];
             old_screen=[2560,1440];
%             %Collect information about new screen:
             set(0,'units','pixels');
             Pix_SS = get(0,'screensize');
%             %Assign coefficient of reduction/increase:
             k_hor=Pix_SS(3)/old_screen(1);
             k_vert=Pix_SS(4)/old_screen(2);
%             %Get size of your original application UI:
%             uisize = app.UIFigure.Position;
%             screenWidth = uisize(3);
%             screenHeight = uisize(4);
%             left = uisize(1);
%             bottom = uisize(2);
%             %Assign new size for the width and height:
%             width = screenWidth*k_hor;
%             height = screenHeight*k_vert;
% 
%             %Assign coefficient to reduce font sizes:
             k_font=(k_hor+k_vert)/2;
             drawnow;
%             %Collect UI elements:
%             %Change UI element size to new scaled size:
%             app.UIFigure.Position = [left bottom width height];
            InitialSetup(app);

        end

        % Button pushed function: LoadDataSetButton
        function LoadDataSetButtonPushed(app, event)
            file = open_mrifile;
            for n=1:length(file)
                file{n} = rename_sequence(file{n});
                sequencelist{n} = file{n}.displayname; %#ok<AGROW>
                try
                    file{n}.backgroundselect = get(app.BackgroundselectionButton,'Value');
                    file{n}.reprocess = get(app.ReprocessingButton,'Value');
                    file{n}.checkfit = get(app.CheckfitsButton,'Value');
                    file{n} = preprocessing(file{n}); %sort out k-space for this sequence
                    file{n}.T1Maps = zeros(file{n}.views,file{n}.samples,file{n}.slices,length(file{n}.fieldpoints));
                    file{n}.R1Maps = zeros(file{n}.views,file{n}.samples,file{n}.slices,length(file{n}.fieldpoints));
                    saveList = file;
                catch ME
                    disp(['Error while processing sequence number ' num2str(n) ':'])
                    disp(ME)
                end
            end
            save('ProcessedImages.mat','saveList'); %after preprocessing we can save the data set so it doesn't have to be done again
            
            app.ScanList.Items = sequencelist;
            app.ScanList.ItemsData =1:numel(app.ScanList.Items); %allows us to get index of selected scan
            app.currentscan = 1; %start on scan 1
            app.scan_data = file; %save the scan to the GUI for easy access
            
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on') %enable all the GUI options now that we have a data set to work on. Errors abound if there is no data set loaded and buttons are pushed!

            activeimage(app) %start drawing images
            
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
          drawimages(app)
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
        drawimages(app)
        end

        % Value changed function: ScanList
        function ScanListValueChanged(app, event)
            activeimage(app)
            
        end

        % Value changed function: SliceDropDown
        function SliceDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: FFTDropDown
        function FFTDropDownValueChanged(app, event)
            updateimages(app)
        end

        % Value changed function: EvolutionTimeDropDown
        function EvolutionTimeDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: EvolutionFieldDropDown
        function EvolutionFieldDropDownValueChanged(app, event)
            disp_field = app.EvolutionFieldDropDown.Value;
            tevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.timepoints(disp_field,:),2)); %update the evolution times
            app.EvolutionTimeDropDown.Items = tevol_list;
            app.EvolutionTimeDropDown.ItemsData = 1:numel(app.EvolutionTimeDropDown.Items);
            drawimages(app)
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            updateimages(app)
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            updateimages(app)
        end

        % Button pushed function: GenerateROIDispersionCurveButton
        function GenerateROIDispersionCurveButtonPushed(app, event)
            delete(app.mask); %had to work with figure with multiple old ROIs drawn on it
            app.mask = drawfreehand(app.axis_image); %I don't like freehands implementation compared to roipoly which doesn't work in AppDesigner - to be revisited
            app.scan_data{app.currentscan}.mask =  createMask( app.mask);
            app.scan_data{app.currentscan}.checkfit = app.CheckfitsButton.Value;
            app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value;
            app.scan_data{app.currentscan}.dispersioncurve = T1dispersion(app.scan_data{app.currentscan});
        end

        % Button pushed function: GenerateRelaxationMapsButton
        function GenerateRelaxationMapsButtonPushed(app, event)
            app.scan_data{app.currentscan}.checkfit = app.CheckfitsButton.Value;
            app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value;
            app.scan_data{app.currentscan} = MapRelaxation(app.scan_data{app.currentscan});
            drawimages(app)
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            theta = 90; %rot90 would work here but defining an affine transformation might be useful for future methods like registration/rescaling
            tform_rotation = affine2d([...
                cosd(theta) sind(theta) 0;...
                -sind(theta) cosd(theta) 0; ...
                0 0 1]);
            app.scan_data{app.currentscan}.rot_theta =   mod((app.scan_data{app.currentscan}.rot_theta + 90),360); %not currently used but a future update will allow the undoing of geometric warping so useful to keep track of it
            %             invtheta = -1*app.scan_data{app.currentscan}.rot_theta;
            %             tform_undo_rotation = affine2d([...
            %                 cosd( invtheta) sind( invtheta) 0;...
            %                 -sind( invtheta) cosd( invtheta) 0; ...
            %                 0 0 1]);
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)

           updateimages(app)
            
        end

        % Value changed function: FilterControlEditField
        function FilterControlEditFieldValueChanged(app, event)
            updateimages(app)
            
        end

        % Value changed function: ColourAxisUpperLimitEditField
        function ColourAxisUpperLimitEditFieldValueChanged(app, event)
         %defines the limits of T1 or R1 that are displayed in the
         %relaxation maps
            if app.ColourAxisUpperLimitEditField.Value < app.ColourAxisLowerLimitEditField.Value
                app.ColourAxisUpperLimitEditField.Value = app.ColourAxisLowerLimitEditField.Value + 1; %stops errors when upperbound is less than lowerbound or vice versa
            end
            
            if app.ColourAxisLowerLimitEditField.Value > app.ColourAxisUpperLimitEditField.Value
                app.ColourAxisLowerLimitEditField.Value = app.ColourAxisUpperLimitEditField.Value - 1;
            end
            
            drawimages(app)
        end

        % Value changed function: ColourAxisLowerLimitEditField
        function ColourAxisLowerLimitEditFieldValueChanged(app, event)
            if app.ColourAxisUpperLimitEditField.Value < app.ColourAxisLowerLimitEditField.Value
               app.ColourAxisUpperLimitEditField.Value = app.ColourAxisLowerLimitEditField.Value + 1;
            end
            
            if app.ColourAxisLowerLimitEditField.Value > app.ColourAxisUpperLimitEditField.Value
               app.ColourAxisLowerLimitEditField.Value = app.ColourAxisUpperLimitEditField.Value - 1;
            end
            
            drawimages(app)
        end

        % Value changed function: LogarithmicMapsButton
        function LogarithmicMapsButtonValueChanged(app, event)
            value = app.LogarithmicMapsButton.Value;
            if value
                app.LogarithmicMapsButton.Text = "Log scale Relaxation Maps";
                %try and select sensible limits when log scale selected
                if  app.ColourAxisLowerLimitEditField.Value <1  %log of 0 is inf
                    app.ColourAxisLowerLimitEditField.Value = 1;
                end
                app.ColourAxisLowerLimitEditField.Value =  log(app.ColourAxisLowerLimitEditField.Value);
                app.ColourAxisUpperLimitEditField.Value =  log(app.ColourAxisUpperLimitEditField.Value);
            else
                app.LogarithmicMapsButton.Text = "Linear scale Relaxation Maps";
                app.ColourAxisLowerLimitEditField.Value =  exp(app.ColourAxisLowerLimitEditField.Value);
                app.ColourAxisUpperLimitEditField.Value =  exp(app.ColourAxisUpperLimitEditField.Value);
            end
            drawimages(app)
            
        end

        % Value changed function: DisplayingasT1Button
        function DisplayingasT1ButtonValueChanged(app, event)
            value = app.DisplayingasT1Button.Value;
            if value
                %try and select sensible limits since R1 is in s-1 and T1
                %is in ms
                app.DisplayingasT1Button.Text = "Displaying as R1";
                app.ColourAxisLowerLimitEditField.Value =  app.ColourAxisLowerLimitEditField.Value/100;
                app.ColourAxisUpperLimitEditField.Value =  app.ColourAxisUpperLimitEditField.Value/100;
            else
                app.DisplayingasT1Button.Text = "Displaying as T1";
                app.ColourAxisLowerLimitEditField.Value =  app.ColourAxisLowerLimitEditField.Value*100;
                app.ColourAxisUpperLimitEditField.Value =  app.ColourAxisUpperLimitEditField.Value*100;
            end
            
            drawimages(app)
        end

        % Button pushed function: ExportscantoworkspaceButton
        function ExportscantoworkspaceButtonPushed(app, event)
        file = app.scan_data{app.currentscan};
if isempty(file.name)
    name = file.sequence;
else
    name = file.name;
end
assignin('base',name,file)
    
        end

        % Button pushed function: 
        % ExportscanparameterstoworkspaceButton
        function ExportscanparameterstoworkspaceButtonPushed(app, event)
            %extract all the relevant scan parameters from the currently
            %selected file and display them in the command window
            file = app.scan_data{app.currentscan};
            disp('--------------------')
            disp(['Experiment name = ',file.name])
            disp(['Sequence name = ',file.sequence])
            disp(['Matrix size = ',num2str(file.samples),' x ',num2str(file.samples)])
            if file.partialkspace == 1
                disp('Partial Fourier = True')
            else
                disp('Partial Fourier = False')
            end
            disp(['Acquisition Bandwidth = ',num2str(file.bandwidth),' KHz'])
            disp(['FOV = ',num2str(file.fov),' mm'])
            disp(['TE = ',num2str(file.TE),' ms'])
            disp(['Flip Angle = ',num2str(file.FlipAngle),' degrees'])
            disp_slice = app.SliceDropDown.Value;
            orientation = file.orientation(:,disp_slice)';
            orientation = num2str(orientation);
            switch orientation
                case '-90   0   0'
                    disp('Orientation = Coronal');
                case '0  0  0'
                    disp('Orientation = Axial');
                otherwise
                    disp(['Orientation = ',orientation]);
            end
            disp(['Slice thickness = ',num2str(file.thk),' mm'])
            disp(['Inplane resolution = = ',num2str(file.resolution_inplane),' mm'])
            disp('Evolution Fields (mT): ')
            disp(num2str(file.fieldpoints,3))
            disp('Evolution Times (ms): ')
            disp(num2str(file.timepoints,3))
            
            
            disp('--------------------')
        end

        % Button pushed function: kspaceButton
        function kspaceButtonPushed(app, event)
            h = figure;
            %get the image as it appears in the axis
            A = getimage(app.axis_kspace);
            cmap = colormap(app.axis_kspace);
            colormap(h,cmap)
            imagesc(A); axis off image
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            h = figure;
            A = getimage(app.axis_image);
            cmap = colormap(app.axis_image);
            colormap(h,cmap)
            if app.ImageTypeDropDown.Value == "Relaxometry Map"
                imshow(A,[app.ColourAxisLowerLimitEditField.Value,app.ColourAxisUpperLimitEditField.Value]);
                colormap(h,'jet')
            elseif app.IndividualNormalisationButton.Value
                imshow(A,[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]);
            else
                imagesc(A); axis off image
            end
            
        end

        % Value changed function: IndividualNormalisationButton
        function IndividualNormalisationButtonValueChanged(app, event)
            value = app.IndividualNormalisationButton.Value;
            if value
                app.IndividualNormalisationButton.Text = "Relative Normalisation";
            else
                app.IndividualNormalisationButton.Text = "Individual Normalisation";
            end
            drawimages(app)
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            theta = 0;
            tform_rotation = affine2d([...
                -cosd(theta) sind(theta) 0;...
                -sind(theta) cosd(theta) 0; ...
                0 0 1]);
            app.scan_data{app.currentscan}.rot_theta =   app.scan_data{app.currentscan}.hor_theta + 180;
%             invtheta = -1*app.scan_data{app.currentscan}.rot_theta;
%             tform_undo_rotation = affine2d([...
%                 cosd( invtheta) sind( invtheta) 0;...
%                 -sind( invtheta) cosd( invtheta) 0; ...
%                 0 0 1]);
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
           
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            theta = 0;
            tform_rotation = affine2d([...
                cosd(theta) sind(theta) 0;...
                -sind(theta) -cosd(theta) 0; ...
                0 0 1]);
            app.scan_data{app.currentscan}.rot_theta =   app.scan_data{app.currentscan}.rot_theta + 90;
%             invtheta = -1*app.scan_data{app.currentscan}.rot_theta;
%             tform_undo_rotation = affine2d([...
%                 cosd( invtheta) sind( invtheta) 0;...
%                 -sind( invtheta) cosd( invtheta) 0; ...
%                 0 0 1]);
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
        end

        % Button pushed function: ExportallimagesButton
        function ExportallimagesButtonPushed(app, event)
            absolute_scale = app.IndividualNormalisationButton.Value;
            ImageType = app.ImageTypeDropDown.Value;
            fieldlist = app.scan_data{app.currentscan}.fieldpoints;
            timepoints =app.scan_data{app.currentscan}.timepoints;
            n_timepoints = app.scan_data{app.currentscan}.n_timepoints;
            n_fieldpoints = app.scan_data{app.currentscan}.n_fieldpoints;
            
            switch ImageType
                case "Relaxometry Map"
                    if app.DisplayingasT1Button.Value
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.R1Maps);
                        tag = 'R1';
                    else
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.T1Maps);
                        tag = 'T1';
                    end
                    
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT_' tag 'map.tif']));
                        minD = app.ColourAxisLowerLimitEditField.Value;
                        maxD = app.ColourAxisUpperLimitEditField.Value;
                        mapped_image = ind2rgb(double(round((1+imagesforexport(:,:,field) - minD) * 255 ./ (maxD - minD))), jet(256)); %this creates a tif RGB image with the same colour scaling as in the GUI
                        imwrite(mapped_image, imagename);
                    end
                    
                case "Phase"
                    imagesforexport = squeeze(app.scan_data{app.currentscan}.phaseimage);
                    tag = 'phasemap';
                    for field = 1:n_fieldpoints
                        for times = 1:n_timepoints
                            imagename = char(([num2str(round(fieldlist(field))) ' mT_' num2str(round(timepoints(field,times))),' ms ' tag '.tif']));
                            imwrite(mat2gray(imagesforexport(:,:,times,field)),imagename);
                        end
                    end
                    
                case "Magnitude"
                    imagesforexport = (app.scan_data{app.currentscan}.magimage);
                   for slice = 1:app.scan_data{app.currentscan}.slices
                    for field = 1:n_fieldpoints
                        for times = 1:n_timepoints
                            imagename = char(([num2str(round(fieldlist(field))) ' mT_' num2str(round(timepoints(field,times))),' ms_slice_' num2str(slice) '.tif']));
                            if absolute_scale
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field),[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]),imagename);
                            else
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field)),imagename);
                            end
                        end
                    end
                   end
            end
            
        end

        % Button pushed function: ExportstackedimagesButton
        function ExportstackedimagesButtonPushed(app, event)
            absolute_scale = app.IndividualNormalisationButton.Value;
            ImageType = app.ImageTypeDropDown.Value;
            fieldlist = app.scan_data{app.currentscan}.fieldpoints;
            n_timepoints = app.scan_data{app.currentscan}.n_timepoints;
            n_fieldpoints = app.scan_data{app.currentscan}.n_fieldpoints;
            
            switch ImageType
                case "Relaxometry Map"
                    if app.DisplayingasT1Button.Value
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.R1Maps);
                        tag = 'R1';
                    else
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.T1Maps);
                        tag = 'T1';
                    end
                    
                    for field = 1:n_fieldpoints
                        imagename = char((['Stacked ' tag 'map.tif']));
                        minD = app.ColourAxisLowerLimitEditField.Value;
                        maxD = app.ColourAxisUpperLimitEditField.Value;
                        mapped_image = ind2rgb(double(round((1+imagesforexport(:,:,field) - minD) * 255 ./ (maxD - minD))), jet(256)); %this creates a tif RGB image with the same colour scaling as in the GUI
                        imwrite(mapped_image, imagename,'WriteMode','append');
                    end
                    
                case "Phase"
                    imagesforexport = squeeze(app.scan_data{app.currentscan}.phaseimage);
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT phase stack.tif']));
                        if exist(imagename, 'file')==2
                            delete(imagename);
                        end
                        for times = 1:n_timepoints
                            
                            imwrite(mat2gray(imagesforexport(:,:,times,field)),imagename,'WriteMode','append'); %writemode append adds new images to an existing one, creating a stacked image
                        end
                    end
                    
                case "Magnitude"
                    imagesforexport = (app.scan_data{app.currentscan}.magimage);
                    for slice = 1:app.scan_data{app.currentscan}.slices
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT stack.tif']));
                        if exist(imagename, 'file')==2
                            delete(imagename);
                        end
                        for times = 1:n_timepoints
                            if absolute_scale
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field),[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]),imagename,'WriteMode','append');
                            else
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field)),imagename,'WriteMode','append');
                            end
                        end
                    end
                    end
            end
        end

        % Button pushed function: ClusterAnalysisButton
        function ClusterAnalysisButtonPushed(app, event)
            try FFCAnalysis_V3(app.scan_data{app.currentscan})
            catch ME %#ok<NASGU>
                errordlg('Error launching FFC Analysis. Check that FFCAnalysis is included in the MATLAB path')
            end
        end

        % Button pushed function: RegisterImagesButton
        function RegisterImagesButtonPushed(app, event)
            k_space = app.scan_data{app.currentscan}.originalcomplexkspace;
imtemp = ifft2c(k_space);
imreg = register_images(imtemp);

% imreg = register_images_rotation(imreg);

app.scan_data{app.currentscan}.originalcomplexkspace =   fft2c(imreg);


updateimages(app)
        end

        % Button pushed function: MeasureSNRButton
        function MeasureSNRButtonPushed(app, event)
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;
            currentim = abs(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));
            tempmask = imbinarize(mat2gray(currentim));
            se = strel('square',10);
            mask2 = imdilate(tempmask,se);
            SNR = 0.655.*(mean(currentim(tempmask==1))./std(currentim(mask2~=1)));
            disp(num2str(SNR));
            
        end

        % Value changed function: MultichannelDropDown
        function MultichannelDropDownValueChanged(app, event)
          updateimages(app)
        end

        % Button pushed function: RebuildImagesButton
        function RebuildImagesButtonPushed(app, event)
           updateimages(app) 
        end

        % Drop down opening function: FilterDropDown
        function FilterDropDownOpening(app, event)

            app.FilterDropDown.Items = retrieveProcessNames('Noise');
        end

        % Window scroll wheel function: UIFigure
        function UIWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            pos = app.UIFigure.CurrentPoint;

            hFound=[];
            for n=1:length(app.editableTags)
                hTag = findobj(app.UIFigure,'Tag', app.editableTags{n});
                hPos = getpixelposition(hTag,true);  % recursive to find absoulute position

                if pos(1)>hPos(1) && pos(1)<hPos(1)+hPos(3) && pos(2)>hPos(2) && pos(2)<hPos(2)+hPos(4)
                    hFound = hTag;
                    break
                end
            end
            if isempty(hFound)
                return
            end
            switch hFound.Type
                case 'uispinner'
                    hFound.Value = hFound.Value - verticalScrollCount*hFound.Step;
                case 'uislider'
                    step = (hFound.Limits(2)-hFound.Limits(1))/100;
                    val = hFound.Value - verticalScrollCount*step;
                    if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                        hFound.Value = hFound.Value - verticalScrollCount*step;
                    end
                case 'uitable'
                    if isempty(hFound.UserData)
                        return;
                    end
                case 'uidropdown'
                    
  
                    temp = hFound.Items;
                    a = num2str(hFound.Value);

                    index = hFound.Value;
                    index = index + verticalScrollCount;
                    
                    if index < 1
                        index  = length(hFound.Items);
                    end
                    
                    if index > length(hFound.Items)
                        index = 1;
                    end
                    

                    hFound.Value = hFound.ItemsData(index);
                    hFound.ValueChangedFcn(app,event);
            end
            
        end

        % Drop down opening function: DenoisingDropDown
        function DenoisingDropDownOpening(app, event)
            app.DenoisingDropDown.Items = retrieveProcessNames('Noise');
        end

        % Button pushed function: SavePipelineButton
        function SavePipelineButtonPushed(app, event)
            
            [file,path] = uiputfile('NewPipeline.txt','Save the Pipeline Values');
            PipelineSettings = {'Automatic_Despiking',app.AutomaticDespikingCheckBox.Value;'Denoising',app.DenoisingDropDown.Value;'R1_Fitting',app.R1FittingDropDown.Value};
            name = append(path, '\' , file);
            writecell(PipelineSettings,name);

        end

        % Button pushed function: LoadPipelineButton
        function LoadPipelineButtonPushed(app, event)
            [file,path] = uigetfile('*.txt',"Select Pipeline to Load");
            settingsPath = append(path, '\' , file);
            PipelineSettings = readcell(settingsPath);
            app.AutomaticDespikingCheckBox.Value = PipelineSettings{1,2};
            app.DenoisingDropDown.Value= PipelineSettings(2,2);
            app.R1FittingDropDown.Value= PipelineSettings(3,2);
        end

        % Button pushed function: BrowseInputDirectorButton
        function BrowseInputDirectorButtonPushed(app, event)
            inputPath = uigetdir(pwd,'Select Data Directory');
            app.InputDirectoryTextLabel.Text = inputPath;
        end

        % Button pushed function: BrowseOutputDirectorButton
        function BrowseOutputDirectorButtonPushed(app, event)
            outputPath = uigetdir(pwd,'Select Data Directory');
            app.OutputDirectoryTextLabel.Text = outputPath;
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            startingDirectory = pwd;
            cd(app.OutputDirectoryTextLabel.Text);
            files = dir();
            dirFlags = [files.isdir];
            dirFolders = files(dirFlags);
            dirFolders(endsWith({dirFolders.name},'PipelineData')) = [];
            dataFolderNames = {dirFolders(3:end).name};


            for k = 1 : length(dataFolderNames)
                
                cd(app.OutputDirectoryTextLabel.Text);
                cd(dataFolderNames{k});
                if isfile('PulseSequenceList.mat')
    
                    file = dir('PulseSequenceList.mat');
                    data = open_datafile(file.name,file.folder);
    
                    for n=1:length(data)
                        data{n} = rename_sequence(data{n});
                        sequencelist{n} = data{n}.displayname; %#ok<AGROW>
                        try
                            data{n}.backgroundselect = get(app.BackgroundselectionButton,'Value');
                            data{n}.reprocess = get(app.ReprocessingButton,'Value');
                            data{n}.checkfit = get(app.CheckfitsButton,'Value');
                            data{n} = pipelinePreprocessing(data{n},app.AutomaticDespikingCheckBox.Value); %sort out k-space for this sequence
                            data{n}.T1Maps = zeros(data{n}.views,data{n}.samples,data{n}.slices,length(data{n}.fieldpoints));
                            data{n}.R1Maps = zeros(data{n}.views,data{n}.samples,data{n}.slices,length(data{n}.fieldpoints));
                            saveList = data;
                        catch ME
                            disp(['Error while processing sequence number ' num2str(n) ':'])
                            disp(ME)
                        end
                    end


                    for n=1:length(saveList)
                                            
                        if isempty(saveList{n}.rot_theta)
                            saveList{n}.rot_theta = 0;
                        end
                        if strcmp(saveList{n}.window_function, 'none')
                            saveList{n}.window_function = 'None';
                        end
    
                        saveList{n}.denoise_filter = app.DenoisingDropDown.Value;
    
                        if isempty(saveList{n}.denoise_params)
                            saveList{n}.denoise_params = 1;
                        end
                        
                        if isempty(saveList{n}.multichannel_recon)
                            saveList{n}.multichannel_recon = 1;
                        end    
                        saveList{n} = buildimages(saveList{n});
                    end
                                    
                    cd(app.InputDirectoryTextLabel.Text);
                    if ~exist('PipelineData', 'dir')
                        mkdir('PipelineData');
                    end
                    cd('PipelineData');
                    imageFileName = append('PipelineData', num2str(k) , '.mat');
                    save(imageFileName,'saveList'); %after preprocessing we can save the data set so it doesn't have to be done again
                end
            end
            cd(startingDirectory);


        end

        % Drop down opening function: R1FittingDropDown
        function R1FittingDropDownOpening(app, event)

            app.R1FittingDropDown.Items = retrieveProcessNames('R1');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIWindowScrollWheel, true);
            app.axis_kspace = uiaxes(app.UIFigure);
            app.axis_kspace.XTick = [];
            app.axis_kspace.YTick = [];
            app.axis_kspace.Tag = 'axis_kspace';
            app.axis_image = uiaxes(app.UIFigure);
            app.axis_image.XTick = [];
            app.axis_image.YTick = [];
            app.axis_image.Tag = 'axis_image';
            app.axis_coordinates = uiaxes(app.UIFigure);
            app.axis_coordinates.XTick = [];
            app.axis_coordinates.YTick = [];
            app.axis_coordinates.Tag = 'axis_image';
            app.panel_recon = uipanel(app.UIFigure);
            app.panel_recon.Title = 'Reconstruction Options';
            app.panel_recon.Tag = 'panel_recon';
            app.SizeofFFTDropDownLabel = uilabel(app.panel_recon);
            app.SizeofFFTDropDownLabel.Enable = 'off';
            app.SizeofFFTDropDownLabel.Text = 'Size of FFT';
            app.FFTDropDown = uidropdown(app.panel_recon);
            app.FFTDropDown.Items = {'64', '128', '256', '512'};
            app.FFTDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTDropDownValueChanged, true);
            app.FFTDropDown.Enable = 'off';
            app.FFTDropDown.Tooltip = {'Size of the FFT. Larger sizes of transform are accomplished by symmetrically zero-padding kspace'};
            app.FFTDropDown.Value = '64';
            app.WindowDropDownLabel = uilabel(app.panel_recon);
            app.WindowDropDownLabel.Enable = 'off';
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.panel_recon);
            app.WindowDropDown.Items = {'None', 'Hann', 'Blackman', 'Hamming', 'Kaiser-Bessel', 'Tukey'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Enable = 'off';
            app.WindowDropDown.Tooltip = {'kspace windowing function'};
            app.WindowDropDown.Value = 'None';
            app.WindowSizeEditFieldLabel = uilabel(app.panel_recon);
            app.WindowSizeEditFieldLabel.Enable = 'off';
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.panel_recon, 'numeric');
            app.WindowSizeEditField.Limits = [0 10];
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowSizeEditField.Enable = 'off';
            app.WindowSizeEditField.Tooltip = {'Size of the windowing function as a fraction of the base image size'};
            app.WindowSizeEditField.Value = 1;
            app.FilterDropDownLabel = uilabel(app.panel_recon);
            app.FilterDropDownLabel.Enable = 'off';
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.panel_recon);
            app.FilterDropDown.Items = {'None', 'Total Variation', 'Generalised Total Variation'};
            app.FilterDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FilterDropDownOpening, true);
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Enable = 'off';
            app.FilterDropDown.Tooltip = {'Denoising filter'};
            app.FilterDropDown.Value = 'None';
            app.FilterControlEditFieldLabel = uilabel(app.panel_recon);
            app.FilterControlEditFieldLabel.Enable = 'off';
            app.FilterControlEditFieldLabel.Text = 'Filter Control';
            app.FilterControlEditField = uieditfield(app.panel_recon, 'numeric');
            app.FilterControlEditField.Limits = [0 1000];
            app.FilterControlEditField.ValueChangedFcn = createCallbackFcn(app, @FilterControlEditFieldValueChanged, true);
            app.FilterControlEditField.Enable = 'off';
            app.FilterControlEditField.Tooltip = {'Kernel size used in debnoising filter'};
            app.FilterControlEditField.Value = 1;
            app.MultichannelRecon = uilabel(app.panel_recon);
            app.MultichannelRecon.Enable = 'off';
            app.MultichannelRecon.Text = 'Multichannel';
            app.MultichannelDropDown = uidropdown(app.panel_recon);
            app.MultichannelDropDown.Items = {'SoS', 'Average', ''};
            app.MultichannelDropDown.ValueChangedFcn = createCallbackFcn(app, @MultichannelDropDownValueChanged, true);
            app.MultichannelDropDown.Enable = 'off';
            app.MultichannelDropDown.Tooltip = {'Multichannel reconstruction options. Currently supports sum of squares and channel averaging. Individual channels can also be inspected.'};
            app.MultichannelDropDown.Value = 'SoS';
            app.RebuildImagesButton = uibutton(app.panel_recon, 'push');
            app.RebuildImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RebuildImagesButtonPushed, true);
            app.RebuildImagesButton.Enable = 'off';
            app.RebuildImagesButton.Tooltip = {'Rebuilds images from phase-corrected k-space. Useful when testing new reconstruction methods'};
            app.RebuildImagesButton.Text = 'Rebuild Images';
            app.CurrentImagePanel = uipanel(app.UIFigure);
            app.CurrentImagePanel.Title = 'Current Image';
            app.EvolutionFieldDropDownLabel = uilabel(app.CurrentImagePanel);
            app.EvolutionFieldDropDownLabel.Enable = 'off';
            app.EvolutionFieldDropDownLabel.Text = 'Evolution Field';
            app.EvolutionFieldDropDown = uidropdown(app.CurrentImagePanel);
            app.EvolutionFieldDropDown.Items = {'200'};
            app.EvolutionFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @EvolutionFieldDropDownValueChanged, true);
            app.EvolutionFieldDropDown.Tag = 'EvoFieldSlice';
            app.EvolutionFieldDropDown.Enable = 'off';
            app.EvolutionFieldDropDown.Tooltip = {'Evolution field in mT'};
            app.EvolutionFieldDropDown.Value = '200';
            app.EvolutionTimeDropDownLabel = uilabel(app.CurrentImagePanel);
            app.EvolutionTimeDropDownLabel.Enable = 'off';
            app.EvolutionTimeDropDownLabel.Text = 'Evolution Time';
            app.EvolutionTimeDropDown = uidropdown(app.CurrentImagePanel);
            app.EvolutionTimeDropDown.Items = {'0'};
            app.EvolutionTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @EvolutionTimeDropDownValueChanged, true);
            app.EvolutionTimeDropDown.Tag = 'EvoTimeSlice';
            app.EvolutionTimeDropDown.Enable = 'off';
            app.EvolutionTimeDropDown.Tooltip = {'Evolution time in ms'};
            app.EvolutionTimeDropDown.Value = '0';
            app.SliceDropDownLabel = uilabel(app.CurrentImagePanel);
            app.SliceDropDownLabel.Enable = 'off';
            app.SliceDropDownLabel.Text = 'Slice';
            app.SliceDropDown = uidropdown(app.CurrentImagePanel);
            app.SliceDropDown.Items = {'1'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceDropDownValueChanged, true);
            app.SliceDropDown.Tag = 'ScrollSlice';
            app.SliceDropDown.Enable = 'off';
            app.SliceDropDown.Tooltip = {'Slice index'};
            app.SliceDropDown.Value = '1';
            app.ImageTypeDropDownLabel = uilabel(app.CurrentImagePanel);
            app.ImageTypeDropDownLabel.Enable = 'off';
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.CurrentImagePanel);
            app.ImageTypeDropDown.Items = {'Magnitude', 'Phase', 'Relaxometry Map'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Enable = 'off';
            app.ImageTypeDropDown.Tooltip = {'Displayed image can be the magnitude image, a phase map or a T1 or R1 parameter map'};
            app.ImageTypeDropDown.Value = 'Magnitude';
            app.RegisterImagesButton = uibutton(app.CurrentImagePanel, 'push');
            app.RegisterImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImagesButtonPushed, true);
            app.RegisterImagesButton.Enable = 'off';
            app.RegisterImagesButton.Text = 'Register Images';
            app.MeasureSNRButton = uibutton(app.CurrentImagePanel, 'push');
            app.MeasureSNRButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureSNRButtonPushed, true);
            app.MeasureSNRButton.Enable = 'off';
            app.MeasureSNRButton.Tooltip = {'Attempts to determine the overall image SNR. This method is better suited to QA style measurements with phantoms rather than in-vivo experiments.'};
            app.MeasureSNRButton.Text = 'Measure SNR';
            app.DisplayOptionsPanel = uipanel(app.UIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.BrightnessSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.BrightnessSliderLabel.Enable = 'off';
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.DisplayOptionsPanel);
            app.BrightnessSlider.Limits = [-1 1];
            app.BrightnessSlider.MajorTicks = [];
            app.BrightnessSlider.MajorTickLabels = {''};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSlider.Enable = 'off';
            app.GammaSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.GammaSliderLabel.Enable = 'off';
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.DisplayOptionsPanel);
            app.GammaSlider.Limits = [0 3];
            app.GammaSlider.MajorTicks = [];
            app.GammaSlider.MajorTickLabels = {''};
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.GammaSlider.MinorTicks = [];
            app.GammaSlider.Enable = 'off';
            app.GammaSlider.Value = 1;
            app.ContrastSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.ContrastSliderLabel.Enable = 'off';
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.DisplayOptionsPanel);
            app.ContrastSlider.Limits = [0 1];
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ContrastSlider.Enable = 'off';
            app.ContrastSlider.Value = 1;
            app.RotateImageButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Enable = 'off';
            app.RotateImageButton.Text = 'Rotate Image';
            app.FlipHorizontallyButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Enable = 'off';
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.FlipVerticallyButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Enable = 'off';
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.IndividualNormalisationButton = uibutton(app.DisplayOptionsPanel, 'state');
            app.IndividualNormalisationButton.ValueChangedFcn = createCallbackFcn(app, @IndividualNormalisationButtonValueChanged, true);
            app.IndividualNormalisationButton.Enable = 'off';
            app.IndividualNormalisationButton.Tooltip = {'Displayed images can either be individually normalised (using imagesc) or else scaled to the maximum intensity of the first image in the data set (usually an image acquired at 200 mt)'};
            app.IndividualNormalisationButton.Text = 'Individual Normalisation';
            app.ScanListPanel = uipanel(app.UIFigure);
            app.ScanListPanel.Title = 'Scan List';
            app.ScanList = uilistbox(app.ScanListPanel);
            app.ScanList.Items = {'Scans will appear here'};
            app.ScanList.ValueChangedFcn = createCallbackFcn(app, @ScanListValueChanged, true);
            app.ScanList.Value = 'Scans will appear here';
            app.RelaxometryPanel = uipanel(app.UIFigure);
            app.RelaxometryPanel.Title = 'Relaxometry';
            app.GenerateRelaxationMapsButton = uibutton(app.RelaxometryPanel, 'push');
            app.GenerateRelaxationMapsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRelaxationMapsButtonPushed, true);
            app.GenerateRelaxationMapsButton.Enable = 'off';
            app.GenerateRelaxationMapsButton.Tooltip = {'Produces voxel-wise relaxation parameter maps. See MapRelaxation for details of how the maps are produced'};
            app.GenerateRelaxationMapsButton.Text = 'Generate Relaxation Maps';
            app.GenerateROIDispersionCurveButton = uibutton(app.RelaxometryPanel, 'push');
            app.GenerateROIDispersionCurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateROIDispersionCurveButtonPushed, true);
            app.GenerateROIDispersionCurveButton.Enable = 'off';
            app.GenerateROIDispersionCurveButton.Tooltip = {'Produces a ROI based dispersion curve. See T1dispersion for details on how the results are derived.'};
            app.GenerateROIDispersionCurveButton.Text = 'Generate ROI Dispersion Curve';
            app.CheckfitsButton = uibutton(app.RelaxometryPanel, 'state');
            app.CheckfitsButton.Enable = 'off';
            app.CheckfitsButton.Tooltip = {'Displays the relaxation fits when either relaxation maps or ROI dispersion curves are generated'};
            app.CheckfitsButton.Text = 'Check fits';
            app.LogarithmicMapsButton = uibutton(app.RelaxometryPanel, 'state');
            app.LogarithmicMapsButton.ValueChangedFcn = createCallbackFcn(app, @LogarithmicMapsButtonValueChanged, true);
            app.LogarithmicMapsButton.Enable = 'off';
            app.LogarithmicMapsButton.Tooltip = {'Visualises relaxation maps either linearly or in log scale. Log scale can be useful for comparing relaxation between different magnetic fields where there is a large change in T1 values'};
            app.LogarithmicMapsButton.Text = 'Logarithmic Maps';
            app.DisplayingasT1Button = uibutton(app.RelaxometryPanel, 'state');
            app.DisplayingasT1Button.ValueChangedFcn = createCallbackFcn(app, @DisplayingasT1ButtonValueChanged, true);
            app.DisplayingasT1Button.Enable = 'off';
            app.DisplayingasT1Button.Tooltip = {'Displays dispersion curves or relaxation maps in terms of R1 or T1'};
            app.DisplayingasT1Button.Text = 'Displaying as T1';
            app.ColourAxisUpperLimitEditFieldLabel = uilabel(app.RelaxometryPanel);
            app.ColourAxisUpperLimitEditFieldLabel.Enable = 'off';
            app.ColourAxisUpperLimitEditFieldLabel.Text = 'Colour Axis Upper Limit';
            app.ColourAxisUpperLimitEditField = uieditfield(app.RelaxometryPanel, 'numeric');
            app.ColourAxisUpperLimitEditField.Limits = [0 Inf];
            app.ColourAxisUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ColourAxisUpperLimitEditFieldValueChanged, true);
            app.ColourAxisUpperLimitEditField.Enable = 'off';
            app.ColourAxisUpperLimitEditField.Tooltip = {'Upper limit of displayed T1/R1 values in relaxation maps'};
            app.ColourAxisUpperLimitEditField.Value = 1000;
            app.ColourAxisLowerLimitEditFieldLabel = uilabel(app.RelaxometryPanel);
            app.ColourAxisLowerLimitEditFieldLabel.Enable = 'off';
            app.ColourAxisLowerLimitEditFieldLabel.Text = 'Colour Axis Lower Limit';
            app.ColourAxisLowerLimitEditField = uieditfield(app.RelaxometryPanel, 'numeric');
            app.ColourAxisLowerLimitEditField.Limits = [0 Inf];
            app.ColourAxisLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ColourAxisLowerLimitEditFieldValueChanged, true);
            app.ColourAxisLowerLimitEditField.Enable = 'off';
            app.ColourAxisLowerLimitEditField.Tooltip = {'Lower limit of displayed T1/R1 values in relaxation maps'};
            app.ClusterAnalysisButton = uibutton(app.RelaxometryPanel, 'push');
            app.ClusterAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterAnalysisButtonPushed, true);
            app.ClusterAnalysisButton.Enable = 'off';
            app.ClusterAnalysisButton.Tooltip = {'Exports the current scan to FFCAnalysis for cluster based relaxation analysis'};
            app.ClusterAnalysisButton.Text = 'Cluster Analysis';
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadDataSetButton = uibutton(app.Panel_2, 'push');
            app.LoadDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSetButtonPushed, true);
            app.LoadDataSetButton.Tooltip = {'Open a FFC data set. '};
            app.LoadDataSetButton.Text = 'Load Data Set';
            app.ReprocessingButton = uibutton(app.Panel_2, 'state');
            app.ReprocessingButton.Tooltip = {'Forces the complete reconstruction of the data set. Otherwise the software will look for preprocessed data and open that instead. Can be time consuming for large data sets.'};
            app.ReprocessingButton.Text = 'Reprocessing';
            app.BackgroundselectionButton = uibutton(app.Panel_2, 'state');
            app.BackgroundselectionButton.Tooltip = {'Allows the user to select the image background to aid in image reconstruction. Usually not needed but may be useful if significant ghosting remains.'};
            app.BackgroundselectionButton.Text = 'Background selection';
            app.ExportOptionsPanel = uipanel(app.UIFigure);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.ExportscantoworkspaceButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportscantoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportscantoworkspaceButtonPushed, true);
            app.ExportscantoworkspaceButton.Enable = 'off';
            app.ExportscantoworkspaceButton.Tooltip = {'Export tje scan object to the MATLAB workspace'};
            app.ExportscantoworkspaceButton.Text = 'Export scan to workspace';
            app.ExportallimagesButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportallimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallimagesButtonPushed, true);
            app.ExportallimagesButton.Enable = 'off';
            app.ExportallimagesButton.Tooltip = {'Export the current data set (magnitude, phase or relaxation map) as a series of .tif images.'};
            app.ExportallimagesButton.Text = 'Export all images';
            app.ExportstackedimagesButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportstackedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportstackedimagesButtonPushed, true);
            app.ExportstackedimagesButton.Enable = 'off';
            app.ExportstackedimagesButton.Tooltip = {'Export the current data set (magnitude, phase or relaxation map) as a stacked .tif image. For magnitude and phase images a stacked image of evolution times will be generated for each evolution field. For relaxation maps a single stack containing all relaxation maps will be generated.'};
            app.ExportstackedimagesButton.Text = 'Export stacked images';
            app.ExportscanparameterstoworkspaceButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportscanparameterstoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportscanparameterstoworkspaceButtonPushed, true);
            app.ExportscanparameterstoworkspaceButton.Enable = 'off';
            app.ExportscanparameterstoworkspaceButton.Tooltip = {'Write the current scan parameters in the workspace'};
            app.ExportscanparameterstoworkspaceButton.Text = 'Export scan parameters to workspace';
            app.CreateFigurePanel = uipanel(app.ExportOptionsPanel);
            app.CreateFigurePanel.Title = 'Create Figure';
            app.kspaceButton = uibutton(app.CreateFigurePanel, 'push');
            app.kspaceButton.ButtonPushedFcn = createCallbackFcn(app, @kspaceButtonPushed, true);
            app.kspaceButton.Enable = 'off';
            app.kspaceButton.Tooltip = {'Produce a new figure containing the currently displayed k-space matrix.'};
            app.kspaceButton.Text = 'k-space';
            app.ImageButton = uibutton(app.CreateFigurePanel, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Tooltip = {'Produce a new figure containing the currently displayed image.'};
            app.ImageButton.Text = 'Image';
            app.PipelineDevelopmentPanel = uipanel(app.UIFigure);
            app.PipelineDevelopmentPanel.Title = 'Pipeline Development';
            app.DenoisingDropDownLabel = uilabel(app.PipelineDevelopmentPanel);
            app.DenoisingDropDownLabel.Text = 'Denoising';
            app.DenoisingDropDown = uidropdown(app.PipelineDevelopmentPanel);
            app.DenoisingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DenoisingDropDownOpening, true);
            app.AutomaticDespikingCheckBox = uicheckbox(app.PipelineDevelopmentPanel);
            app.AutomaticDespikingCheckBox.Text = 'Automatic Despiking';
            app.DeghostingLabel = uilabel(app.PipelineDevelopmentPanel);
            app.DeghostingLabel.Text = 'Deghosting';
            app.R1FittingDropDownLabel = uilabel(app.PipelineDevelopmentPanel);
            app.R1FittingDropDownLabel.Text = 'R1 Fitting';
            app.R1FittingDropDown = uidropdown(app.PipelineDevelopmentPanel);
            app.R1FittingDropDown.Items = {'fit_relaxation_V2', 'Option 2', 'Option 3', 'Option 4'};
            app.R1FittingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @R1FittingDropDownOpening, true);
            app.R1FittingDropDown.Value = 'fit_relaxation_V2';
            app.BrowseInputDirectorButton = uibutton(app.PipelineDevelopmentPanel, 'push');
            app.BrowseInputDirectorButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputDirectorButtonPushed, true);
            app.BrowseInputDirectorButton.Text = 'Browse';
            app.LoadPipelineButton = uibutton(app.PipelineDevelopmentPanel, 'push');
            app.LoadPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPipelineButtonPushed, true);
            app.LoadPipelineButton.Text = 'Load Pipeline';
            app.SavePipelineButton = uibutton(app.PipelineDevelopmentPanel, 'push');
            app.SavePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SavePipelineButtonPushed, true);
            app.SavePipelineButton.Text = 'Save Pipeline';
            app.RunPipelineButton = uibutton(app.PipelineDevelopmentPanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run';
            app.BrowseOutputDirectorButton = uibutton(app.PipelineDevelopmentPanel, 'push');
            app.BrowseOutputDirectorButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputDirectorButtonPushed, true);
            app.BrowseOutputDirectorButton.Text = 'Browse';
            app.OutputDirectoryTextLabel = uilabel(app.PipelineDevelopmentPanel);
            app.OutputDirectoryTextLabel.Text = '...';
            app.InputDirectoryTextLabel = uilabel(app.PipelineDevelopmentPanel);
            app.InputDirectoryTextLabel.Text = '...';
            app.OutputDirectoryLabel = uilabel(app.PipelineDevelopmentPanel);
            app.OutputDirectoryLabel.Text = 'Select Output Folder';
            app.InputDirectoryLabel = uilabel(app.PipelineDevelopmentPanel);
            app.InputDirectoryLabel.Text = 'Select Input Data Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFCProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end